classdef exemplo_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_WindowUIFigure          matlab.ui.Figure
        GerarreltorioButton          matlab.ui.control.Button
        ConfiguraesPanel             matlab.ui.container.Panel
        SKUButton                    matlab.ui.control.Button
        SetupMquinasButton           matlab.ui.control.Button
        PulmesButton                 matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        CalculadoradepontotimoLabel  matlab.ui.control.Label
        mainREditFieldLabel          matlab.ui.control.Label
        mainREditField               matlab.ui.control.NumericEditField
        mainSEditFieldLabel          matlab.ui.control.Label
        mainSEditField               matlab.ui.control.NumericEditField
        HelpButton                   matlab.ui.control.Button
        MTBF1EditFieldLabel          matlab.ui.control.Label
        MTBF1EditField               matlab.ui.control.NumericEditField
        MTTR1EditFieldLabel          matlab.ui.control.Label
        MTTR1EditField               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        DialogApp                   % Options dialog app
    end

    methods (Access = public)
    
        function updateVALORES(app,DialogS)
            
            % update dos valores relacionados ao DialogBox
            app.mainREditField.Value = DialogS;
           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcnMAIN(app)

        end

        % Button pushed function: SetupMquinasButton
        function SetupMaquinasButtonPushed(app, event)
            x = app.mainSEditField.Value;
            
            
            % Call dialog box with input values
            app.DialogApp = DialogBox_SETUPMAQUINA(app,x);
        end

        % Button pushed function: SKUButton
        function SKUButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = DialogBox_SKU(app, app.mainREditField);
        end

        % Button pushed function: PulmesButton
        function PulmesButtonPushed(app, event)
            app.DialogApp = DialogBox_PULMOES();
        end

        % Button pushed function: GerarreltorioButton
        function GerarreltorioButtonPushed(app, event)
            app.DialogApp = DialogBox_RELATORIO();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_WindowUIFigure = uifigure;
            app.Main_WindowUIFigure.Name = 'Main_Window';
            app.GerarreltorioButton = uibutton(app.Main_WindowUIFigure, 'push');
            app.GerarreltorioButton.ButtonPushedFcn = createCallbackFcn(app, @GerarreltorioButtonPushed, true);
            app.GerarreltorioButton.Text = 'Gerar relátorio';
            app.ConfiguraesPanel = uipanel(app.Main_WindowUIFigure);
            app.ConfiguraesPanel.Title = 'Configurações:';
            app.SKUButton = uibutton(app.ConfiguraesPanel, 'push');
            app.SKUButton.ButtonPushedFcn = createCallbackFcn(app, @SKUButtonPushed, true);
            app.SKUButton.Text = 'SKU';
            app.SetupMquinasButton = uibutton(app.ConfiguraesPanel, 'push');
            app.SetupMquinasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupMaquinasButtonPushed, true);
            app.SetupMquinasButton.Text = 'Setup Máquinas';
            app.PulmesButton = uibutton(app.ConfiguraesPanel, 'push');
            app.PulmesButton.ButtonPushedFcn = createCallbackFcn(app, @PulmesButtonPushed, true);
            app.PulmesButton.Text = 'Pulmões';
            app.Button = uibutton(app.Main_WindowUIFigure, 'push');
            app.Button.Icon = 'insper_logo.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Main_WindowUIFigure, 'push');
            app.Button_2.Icon = 'Coca-Cola_Femsa_Logo.png';
            app.Button_2.Text = '';
            app.CalculadoradepontotimoLabel = uilabel(app.Main_WindowUIFigure);
            app.CalculadoradepontotimoLabel.Text = 'Calculadora de ponto ótimo';
            app.mainREditFieldLabel = uilabel(app.Main_WindowUIFigure);
            app.mainREditFieldLabel.Text = 'main R';
            app.mainREditField = uieditfield(app.Main_WindowUIFigure, 'numeric');
            app.mainREditField.ValueDisplayFormat = '%.0f';
            app.mainSEditFieldLabel = uilabel(app.Main_WindowUIFigure);
            app.mainSEditFieldLabel.Text = 'main S';
            app.mainSEditField = uieditfield(app.Main_WindowUIFigure, 'numeric');
            app.mainSEditField.ValueDisplayFormat = '%.0f';
            app.HelpButton = uibutton(app.Main_WindowUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.MTBF1EditFieldLabel = uilabel(app.Main_WindowUIFigure);
            app.MTBF1EditFieldLabel.Text = 'MTBF1';
            app.MTBF1EditField = uieditfield(app.Main_WindowUIFigure, 'numeric');
            app.MTTR1EditFieldLabel = uilabel(app.Main_WindowUIFigure);
            app.MTTR1EditFieldLabel.Text = 'MTTR1';
            app.MTTR1EditField = uieditfield(app.Main_WindowUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = exemplo_Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_WindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcnMAIN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_WindowUIFigure)
        end
    end
end