classdef DialogBox_PULMOES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        reapulmoTab    matlab.ui.container.Tab
        Button_3       matlab.ui.control.Button
        ObsreadopulmoNreferenteaopulmofrentedamquinaNLabel_2  matlab.ui.control.Label
        OKButton_2     matlab.ui.control.Button
        DimensesPanel  matlab.ui.container.Panel
        pulmo1Label    matlab.ui.control.Label
        pulmo2Label    matlab.ui.control.Label
        pulmo3Label    matlab.ui.control.Label
        pulmo4Label    matlab.ui.control.Label
        pulmo5Label    matlab.ui.control.Label
        pulmo6Label    matlab.ui.control.Label
        pulmo7Label    matlab.ui.control.Label
        pulmo8Label    matlab.ui.control.Label
        Panel_4        matlab.ui.container.Panel
        mLabel         matlab.ui.control.Label
        L1             matlab.ui.control.NumericEditField
        L2             matlab.ui.control.NumericEditField
        L3             matlab.ui.control.NumericEditField
        L4             matlab.ui.control.NumericEditField
        L5             matlab.ui.control.NumericEditField
        L6             matlab.ui.control.NumericEditField
        L7             matlab.ui.control.NumericEditField
        L8             matlab.ui.control.NumericEditField
        LLabel         matlab.ui.control.Label
        Panel_5        matlab.ui.container.Panel
        mLabel_2       matlab.ui.control.Label
        CLabel         matlab.ui.control.Label
        c1             matlab.ui.control.NumericEditField
        c2             matlab.ui.control.NumericEditField
        c3             matlab.ui.control.NumericEditField
        c4             matlab.ui.control.NumericEditField
        c5             matlab.ui.control.NumericEditField
        c6             matlab.ui.control.NumericEditField
        c7             matlab.ui.control.NumericEditField
        c8             matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp   % Main app
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Callback function
        function OKButton_3Pushed(app, event)
            % delete options dialog
            delete(app)
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            %{
            updateVALORESPULMAO(app.CallingApp, app.areapulmo1EditField.Value, app.areapulmo2EditField.Value,... 
            app.areapulmo3EditField.Value,app.areapulmo4EditField.Value,app.areapulmo5EditField.Value,...
            app.areapulmo6EditField.Value,app.areapulmo7EditField.Value,app.areapulmo8EditField.Value);
            %}
            updateVALORESPULMAO(app.CallingApp, app.L1.Value,app.L2.Value,app.L3.Value,app.L4.Value,app.L5.Value,app.L6.Value,...
            app.L7.Value,app.L8.Value,app.c1.Value,app.c2.Value,app.c3.Value,app.c4.Value,app.c5.Value,app.c6.Value,...
            app.c7.Value,app.c8.Value);
            
            
            % delete options dialog
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.reapulmoTab = uitab(app.TabGroup);
            app.reapulmoTab.Title = 'Área pulmão';
            app.Button_3 = uibutton(app.reapulmoTab, 'push');
            app.Button_3.Icon = 'foto_A_pulmao2.PNG';
            app.Button_3.Text = '';
            app.ObsreadopulmoNreferenteaopulmofrentedamquinaNLabel_2 = uilabel(app.reapulmoTab);
            app.ObsreadopulmoNreferenteaopulmofrentedamquinaNLabel_2.Text = {'Obs: área do pulmão N é referente ao pulmão à frente da '; 'máquina N. Caso as informações das dimensões do pulmão '; 'sejam desconhecidas e apenas a Área é conhecida basta '; 'colocar o valor 1 no campo L(m) e a área no campo C(m).'};
            app.OKButton_2 = uibutton(app.reapulmoTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.DimensesPanel = uipanel(app.reapulmoTab);
            app.DimensesPanel.Title = 'Dimensões';
            app.pulmo1Label = uilabel(app.reapulmoTab);
            app.pulmo1Label.Text = 'pulmão 1:';
            app.pulmo2Label = uilabel(app.reapulmoTab);
            app.pulmo2Label.Text = 'pulmão 2:';
            app.pulmo3Label = uilabel(app.reapulmoTab);
            app.pulmo3Label.Text = 'pulmão 3:';
            app.pulmo4Label = uilabel(app.reapulmoTab);
            app.pulmo4Label.Text = 'pulmão 4:';
            app.pulmo5Label = uilabel(app.reapulmoTab);
            app.pulmo5Label.Text = 'pulmão 5:';
            app.pulmo6Label = uilabel(app.reapulmoTab);
            app.pulmo6Label.Text = 'pulmão 6:';
            app.pulmo7Label = uilabel(app.reapulmoTab);
            app.pulmo7Label.Text = 'pulmão 7:';
            app.pulmo8Label = uilabel(app.reapulmoTab);
            app.pulmo8Label.Text = 'pulmão 8:';
            app.Panel_4 = uipanel(app.reapulmoTab);
            app.mLabel = uilabel(app.Panel_4);
            app.mLabel.Text = '(m)';
            app.L1 = uieditfield(app.Panel_4, 'numeric');
            app.L1.ValueDisplayFormat = '%.0f';
            app.L2 = uieditfield(app.Panel_4, 'numeric');
            app.L2.ValueDisplayFormat = '%.0f';
            app.L3 = uieditfield(app.Panel_4, 'numeric');
            app.L3.ValueDisplayFormat = '%.0f';
            app.L4 = uieditfield(app.Panel_4, 'numeric');
            app.L4.ValueDisplayFormat = '%.0f';
            app.L5 = uieditfield(app.Panel_4, 'numeric');
            app.L5.ValueDisplayFormat = '%.0f';
            app.L6 = uieditfield(app.Panel_4, 'numeric');
            app.L6.ValueDisplayFormat = '%.0f';
            app.L7 = uieditfield(app.Panel_4, 'numeric');
            app.L7.ValueDisplayFormat = '%.0f';
            app.L8 = uieditfield(app.Panel_4, 'numeric');
            app.L8.ValueDisplayFormat = '%.0f';
            app.LLabel = uilabel(app.Panel_4);
            app.LLabel.Text = 'L';
            app.Panel_5 = uipanel(app.reapulmoTab);
            app.mLabel_2 = uilabel(app.Panel_5);
            app.mLabel_2.Text = '(m)';
            app.CLabel = uilabel(app.Panel_5);
            app.CLabel.Text = 'C';
            app.c1 = uieditfield(app.Panel_5, 'numeric');
            app.c1.ValueDisplayFormat = '%.0f';
            app.c2 = uieditfield(app.Panel_5, 'numeric');
            app.c2.ValueDisplayFormat = '%.0f';
            app.c3 = uieditfield(app.Panel_5, 'numeric');
            app.c3.ValueDisplayFormat = '%.0f';
            app.c4 = uieditfield(app.Panel_5, 'numeric');
            app.c4.ValueDisplayFormat = '%.0f';
            app.c5 = uieditfield(app.Panel_5, 'numeric');
            app.c5.ValueDisplayFormat = '%.0f';
            app.c6 = uieditfield(app.Panel_5, 'numeric');
            app.c6.ValueDisplayFormat = '%.0f';
            app.c7 = uieditfield(app.Panel_5, 'numeric');
            app.c7.ValueDisplayFormat = '%.0f';
            app.c8 = uieditfield(app.Panel_5, 'numeric');
            app.c8.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogBox_PULMOES(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end