classdef DialogBox_RELATORIO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OkButton                 matlab.ui.control.Button
        VelocidadesPanel         matlab.ui.container.Panel
        mquina1EditFieldLabel    matlab.ui.control.Label
        m1                       matlab.ui.control.NumericEditField
        mquina2EditFieldLabel    matlab.ui.control.Label
        m2                       matlab.ui.control.NumericEditField
        mquina3EditFieldLabel    matlab.ui.control.Label
        m3                       matlab.ui.control.NumericEditField
        mquina4Label             matlab.ui.control.Label
        m4                       matlab.ui.control.NumericEditField
        mquina5EditFieldLabel    matlab.ui.control.Label
        m5                       matlab.ui.control.NumericEditField
        mquina6Label             matlab.ui.control.Label
        m6                       matlab.ui.control.NumericEditField
        mquina7EditFieldLabel    matlab.ui.control.Label
        m7                       matlab.ui.control.NumericEditField
        mquina8EditFieldLabel    matlab.ui.control.Label
        m8                       matlab.ui.control.NumericEditField
        EficinciadalinhaLabel    matlab.ui.control.Label
        eficencialinha           matlab.ui.control.NumericEditField
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        DisponibilidadePanel     matlab.ui.container.Panel
        mquina1EditField_2Label  matlab.ui.control.Label
        disp1                    matlab.ui.control.NumericEditField
        mquina2EditField_2Label  matlab.ui.control.Label
        disp2                    matlab.ui.control.NumericEditField
        mquina3EditField_2Label  matlab.ui.control.Label
        disp3                    matlab.ui.control.NumericEditField
        mquina4EditField_2Label  matlab.ui.control.Label
        disp4                    matlab.ui.control.NumericEditField
        mquina5EditField_2Label  matlab.ui.control.Label
        disp5                    matlab.ui.control.NumericEditField
        mquina6EditField_2Label  matlab.ui.control.Label
        disp6                    matlab.ui.control.NumericEditField
        mquina7EditField_2Label  matlab.ui.control.Label
        disp7                    matlab.ui.control.NumericEditField
        mquina8EditField_2Label  matlab.ui.control.Label
        disp8                    matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CallingApp   % Main app
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcnRELATORIO(app, mainapp)
            
            % ------------------------------------- Calculo das velocidades ---------------------------------------
            
            % criação das listas
            posGar = mainapp.pGar;
            AreaP = mainapp.AreaP;
            AreaSku = mainapp.AreaSku;
            MTTR = mainapp.MTTR;
            MTBF = mainapp.MTBF;
            Gar = mainapp.GAR;
            
            % filtro do valores da lista
            TF_MTTR = find(MTTR==0);
            MTTR(TF_MTTR) = [];
            TF_MTBF = find(MTBF==0);
            MTBF(TF_MTBF) = [];
            TF_AreaP = find(AreaP==0);
            AreaP(TF_AreaP) = [];
            
            AreaSku = AreaSku(1:length(MTTR));
            
            Produtos = AreaP./(AreaSku/1000);
            PercentParada = MTTR./(MTTR+MTBF);
            MTPH = 60*PercentParada; % media de tempo de parada por hora


             VRL = Gar-(Gar*PercentParada(posGar)); % valor real da linha
             % calculo da eficiencia
             app.eficencialinha.Value = MTBF(posGar)/(MTBF(posGar)+MTTR(posGar));
          
            
            SVs = VRL+Gar*PercentParada;% sobre velocidade
            GarPos = find(SVs==min(SVs));
            if (length(GarPos)==1)
                SVsaux1 = SVs(1:GarPos-1);
                SVsaux2 = SVs(GarPos+1:end);
                for i = 1:length(SVsaux1)
                    Over = 1+(((length(SVsaux1)+1)-i)*(0.03));
                    SVsaux1(i) = SVsaux1(i)*Over;
                end
                for i= 1:length(SVsaux2)
                    Over = 1+(0.03*i);
                    SVsaux2(i) = SVsaux2(i)*Over;
                end
                SVs = cat(2,SVsaux1,SVs(GarPos),SVsaux2);
            end
            
            SVpercent = SVs./sum(Gar);
            TEP = (Produtos./SVs)*60;
            
            X = [1 2 3 4 5 6 7 8];
            X = X(1:length(MTTR));
            
            % Plot do V-Graph
            plot(app.UIAxes,X,SVpercent,'b-o');
            
            
            % Plot das velocidades máquinas
            try
                app.m1.Value = SVs(1);
            catch
                app.m1.Value = 0;
            end
            
            try
                app.m2.Value = SVs(2);
            catch
                app.m2.Value = 0;
            end
            
            try
                app.m3.Value = SVs(3);
            catch
                app.m3.Value = 0;
            end
            
            try
                app.m4.Value = SVs(4);
            catch
                app.m4.Value = 0;
            end
            
            try
                app.m5.Value = SVs(5);
            catch
                app.m5.Value = 0;
            end
            
            try
                app.m6.Value = SVs(6);
            catch
                app.m6.Value = 0;
            end
            
            try
                app.m7.Value = SVs(7);
            catch
                app.m7.Value = 0;
            end
            
            try
                app.m8.Value = SVs(8);
            catch
                app.m8.Value = 0;
            end
            
            % Plot das disponibilidades
            try
                app.disp1.Value = MTBF(1)/(MTBF(1)+MTTR(1));
            catch
                app.disp1.Value = 0;
            end
            
            try
                app.disp2.Value = MTBF(2)/(MTBF(2)+MTTR(2));
            catch
                app.disp2.Value = 0;
            end
            
            try
                app.disp3.Value = MTBF(3)/(MTBF(3)+MTTR(3));
            catch
                app.disp3.Value = 0;
            end
            
            try
                app.disp4.Value = MTBF(4)/(MTBF(4)+MTTR(4));
            catch
                app.disp4.Value = 0;
            end
            
            try
                app.disp5.Value = MTBF(5)/(MTBF(5)+MTTR(5));
            catch
                app.disp5.Value = 0;
            end
            
            try
                app.disp6.Value = MTBF(6)/(MTBF(6)+MTTR(6));
            catch
                app.disp6.Value = 0;
            end
            
            try
                app.disp7.Value = MTBF(7)/(MTBF(7)+MTTR(7));
            catch
                app.disp7.Value = 0;
            end
            
            try
                app.disp8.Value = MTBF(8)/(MTBF(8)+MTTR(8));
            catch
                app.disp8.Value= 0;
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % delete options dialog
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.VelocidadesPanel = uipanel(app.UIFigure);
            app.VelocidadesPanel.Title = 'Velocidades';
            app.mquina1EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina1EditFieldLabel.Text = 'máquina 1:';
            app.m1 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.m1.ValueDisplayFormat = '%.0f';
            app.m1.Editable = 'off';
            app.mquina2EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina2EditFieldLabel.Text = 'máquina 2:';
            app.m2 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.m2.ValueDisplayFormat = '%.0f';
            app.mquina3EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina3EditFieldLabel.Text = 'máquina 3:';
            app.m3 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.m3.ValueDisplayFormat = '%.0f';
            app.mquina4Label = uilabel(app.VelocidadesPanel);
            app.mquina4Label.Text = 'máquina 4:';
            app.m4 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.m4.ValueDisplayFormat = '%.0f';
            app.mquina5EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina5EditFieldLabel.Text = 'máquina 5:';
            app.m5 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.mquina6Label = uilabel(app.VelocidadesPanel);
            app.mquina6Label.Text = 'máquina 6:';
            app.m6 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.mquina7EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina7EditFieldLabel.Text = 'máquina 7:';
            app.m7 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.mquina8EditFieldLabel = uilabel(app.VelocidadesPanel);
            app.mquina8EditFieldLabel.Text = 'máquina 8:';
            app.m8 = uieditfield(app.VelocidadesPanel, 'numeric');
            app.EficinciadalinhaLabel = uilabel(app.UIFigure);
            app.EficinciadalinhaLabel.Text = 'Eficiência da linha:';
            app.eficencialinha = uieditfield(app.UIFigure, 'numeric');
            app.eficencialinha.ValueDisplayFormat = '%.2f';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'insper_logo.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'Coca-Cola_Femsa_Logo.png';
            app.Button_2.Text = '';
            app.DisponibilidadePanel = uipanel(app.UIFigure);
            app.DisponibilidadePanel.Title = 'Disponibilidade';
            app.mquina1EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina1EditField_2Label.Text = 'máquina 1:';
            app.disp1 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp1.ValueDisplayFormat = '%.2f';
            app.disp1.Editable = 'off';
            app.mquina2EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina2EditField_2Label.Text = 'máquina 2:';
            app.disp2 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp2.ValueDisplayFormat = '%.2f';
            app.mquina3EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina3EditField_2Label.Text = 'máquina 3:';
            app.disp3 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp3.ValueDisplayFormat = '%.2f';
            app.mquina4EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina4EditField_2Label.Text = 'máquina 4:';
            app.disp4 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp4.ValueDisplayFormat = '%.2f';
            app.mquina5EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina5EditField_2Label.Text = 'máquina 5:';
            app.disp5 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp5.ValueDisplayFormat = '%.2f';
            app.mquina6EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina6EditField_2Label.Text = 'máquina 6:';
            app.disp6 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp6.ValueDisplayFormat = '%.2f';
            app.mquina7EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina7EditField_2Label.Text = 'máquina 7:';
            app.disp7 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp7.ValueDisplayFormat = '%.2f';
            app.mquina8EditField_2Label = uilabel(app.DisponibilidadePanel);
            app.mquina8EditField_2Label.Text = 'máquina 8:';
            app.disp8 = uieditfield(app.DisponibilidadePanel, 'numeric');
            app.disp8.ValueDisplayFormat = '%.2f';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfico V')
            xlabel(app.UIAxes, 'N máquinas')
            ylabel(app.UIAxes, 'Sobrevelovidades')
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogBox_RELATORIO(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcnRELATORIO(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end