classdef DialogBox_SETUPMAQUINAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupMquinasUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MTBFeMTTRTab                    matlab.ui.container.Tab
        QualoMTBFeMTTRdasmquinasLabel   matlab.ui.control.Label
        ObsDeixaroEditFieldemzeroparamquinasquenoexistemnalinhaLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        dialogMTBF1                     matlab.ui.control.NumericEditField
        dialogMTBF2                     matlab.ui.control.NumericEditField
        dialogMTBF3                     matlab.ui.control.NumericEditField
        dialogMTBF4                     matlab.ui.control.NumericEditField
        dialogMTBF5                     matlab.ui.control.NumericEditField
        dialogMTBF6                     matlab.ui.control.NumericEditField
        dialogMTBF7                     matlab.ui.control.NumericEditField
        dialogMTBF8                     matlab.ui.control.NumericEditField
        MTTRLabel                       matlab.ui.control.Label
        MTBFLabel_2                     matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        minLabel_2                      matlab.ui.control.Label
        mquina1EditFieldLabel           matlab.ui.control.Label
        dialogMTTR1                     matlab.ui.control.NumericEditField
        mquina2EditFieldLabel           matlab.ui.control.Label
        dialogMTTR2                     matlab.ui.control.NumericEditField
        mquina3EditFieldLabel           matlab.ui.control.Label
        dialogMTTR3                     matlab.ui.control.NumericEditField
        mquina4EditFieldLabel           matlab.ui.control.Label
        dialogMTTR4                     matlab.ui.control.NumericEditField
        mquina5EditFieldLabel           matlab.ui.control.Label
        dialogMTTR5                     matlab.ui.control.NumericEditField
        mquina6EditFieldLabel           matlab.ui.control.Label
        dialogMTTR6                     matlab.ui.control.NumericEditField
        mquina7EditFieldLabel           matlab.ui.control.Label
        dialogMTTR7                     matlab.ui.control.NumericEditField
        mquina8EditFieldLabel           matlab.ui.control.Label
        dialogMTTR8                     matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        GargaloTab                      matlab.ui.container.Tab
        QualaposiodagargaloLabel        matlab.ui.control.Label
        QualcapacidadedestamquinaLabel  matlab.ui.control.Label
        velocidadeEditFieldLabel        matlab.ui.control.Label
        veloGAR                         matlab.ui.control.NumericEditField
        OKButton_3                      matlab.ui.control.Button
        SkuhLabel                       matlab.ui.control.Label
        posiomquinaLabel                matlab.ui.control.Label
        posGar                          matlab.ui.control.NumericEditField
        Button_4                        matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Button pushed function: OKButton_3
        function OKButton_3Pushed(app, event)
            updateVALORESMAQUINA(app.CallingApp,app.veloGAR.Value,app.dialogMTBF1.Value,app.dialogMTBF2.Value,app.dialogMTBF3.Value,app.dialogMTBF4.Value,...
            app.dialogMTBF5.Value,app.dialogMTBF6.Value,app.dialogMTBF7.Value,app.dialogMTBF8.Value,app.dialogMTTR1.Value,app.dialogMTTR2.Value,app.dialogMTTR3.Value,app.dialogMTTR4.Value,...
            app.dialogMTTR5.Value,app.dialogMTTR6.Value,app.dialogMTTR7.Value,app.dialogMTTR8.Value,app.posGar.Value);      
           
            % delete options dialog
            %delete(app);
        end

        % Callback function
        function OKButtonNMAQUINA1Pushed(app, event)
            updateVALORESMAQUINA(app.CallingApp,app.veloGAR.Value,app.dialogMTBF1.Value,app.dialogMTBF2.Value,app.dialogMTBF3.Value,app.dialogMTBF4.Value,...
            app.dialogMTBF5.Value,app.dialogMTBF6.Value,app.dialogMTBF7.Value,app.dialogMTBF8.Value,app.dialogMTTR1.Value,app.dialogMTTR2.Value,app.dialogMTTR3.Value,app.dialogMTTR4.Value,...
            app.dialogMTTR5.Value,app.dialogMTTR6.Value,app.dialogMTTR7.Value,app.dialogMTTR8.Value);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupMquinasUIFigure = uifigure;
            app.SetupMquinasUIFigure.Name = 'Setup Máquinas';
            app.TabGroup = uitabgroup(app.SetupMquinasUIFigure);
            app.MTBFeMTTRTab = uitab(app.TabGroup);
            app.MTBFeMTTRTab.Title = 'MTBF e MTTR';
            app.QualoMTBFeMTTRdasmquinasLabel = uilabel(app.MTBFeMTTRTab);
            app.QualoMTBFeMTTRdasmquinasLabel.Text = 'Qual o MTBF e MTTR das máquinas?';
            app.ObsDeixaroEditFieldemzeroparamquinasquenoexistemnalinhaLabel = uilabel(app.MTBFeMTTRTab);
            app.ObsDeixaroEditFieldemzeroparamquinasquenoexistemnalinhaLabel.Text = 'Obs: Deixar o Edit Field em zero para máquinas que não existem na linha';
            app.Panel = uipanel(app.MTBFeMTTRTab);
            app.dialogMTBF1 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF2 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF3 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF4 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF5 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF6 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF7 = uieditfield(app.Panel, 'numeric');
            app.dialogMTBF8 = uieditfield(app.Panel, 'numeric');
            app.MTTRLabel = uilabel(app.Panel);
            app.MTTRLabel.Text = 'MTTR';
            app.MTBFLabel_2 = uilabel(app.Panel);
            app.MTBFLabel_2.Text = 'MTBF';
            app.minLabel = uilabel(app.Panel);
            app.minLabel.Text = 'min';
            app.minLabel_2 = uilabel(app.Panel);
            app.minLabel_2.Text = 'min';
            app.mquina1EditFieldLabel = uilabel(app.Panel);
            app.mquina1EditFieldLabel.Text = 'máquina 1:';
            app.dialogMTTR1 = uieditfield(app.Panel, 'numeric');
            app.mquina2EditFieldLabel = uilabel(app.Panel);
            app.mquina2EditFieldLabel.Text = 'máquina 2:';
            app.dialogMTTR2 = uieditfield(app.Panel, 'numeric');
            app.mquina3EditFieldLabel = uilabel(app.Panel);
            app.mquina3EditFieldLabel.Text = 'máquina 3:';
            app.dialogMTTR3 = uieditfield(app.Panel, 'numeric');
            app.mquina4EditFieldLabel = uilabel(app.Panel);
            app.mquina4EditFieldLabel.Text = 'máquina 4:';
            app.dialogMTTR4 = uieditfield(app.Panel, 'numeric');
            app.mquina5EditFieldLabel = uilabel(app.Panel);
            app.mquina5EditFieldLabel.Text = 'máquina 5:';
            app.dialogMTTR5 = uieditfield(app.Panel, 'numeric');
            app.mquina6EditFieldLabel = uilabel(app.Panel);
            app.mquina6EditFieldLabel.Text = 'máquina 6:';
            app.dialogMTTR6 = uieditfield(app.Panel, 'numeric');
            app.mquina7EditFieldLabel = uilabel(app.Panel);
            app.mquina7EditFieldLabel.Text = 'máquina 7:';
            app.dialogMTTR7 = uieditfield(app.Panel, 'numeric');
            app.mquina8EditFieldLabel = uilabel(app.Panel);
            app.mquina8EditFieldLabel.Text = 'máquina 8:';
            app.dialogMTTR8 = uieditfield(app.Panel, 'numeric');
            app.Button = uibutton(app.MTBFeMTTRTab, 'push');
            app.Button.Icon = 'foto_planta.PNG';
            app.Button.Text = '';
            app.GargaloTab = uitab(app.TabGroup);
            app.GargaloTab.Title = 'Gargalo';
            app.QualaposiodagargaloLabel = uilabel(app.GargaloTab);
            app.QualaposiodagargaloLabel.Text = 'Qual a posição da gargalo?';
            app.QualcapacidadedestamquinaLabel = uilabel(app.GargaloTab);
            app.QualcapacidadedestamquinaLabel.Text = 'Qual capacidade desta máquina?';
            app.velocidadeEditFieldLabel = uilabel(app.GargaloTab);
            app.velocidadeEditFieldLabel.Text = 'velocidade:';
            app.veloGAR = uieditfield(app.GargaloTab, 'numeric');
            app.veloGAR.ValueDisplayFormat = '%.0f';
            app.OKButton_3 = uibutton(app.GargaloTab, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_3Pushed, true);
            app.OKButton_3.Text = 'OK';
            app.SkuhLabel = uilabel(app.GargaloTab);
            app.SkuhLabel.Text = 'Sku/h';
            app.posiomquinaLabel = uilabel(app.GargaloTab);
            app.posiomquinaLabel.Text = 'posição máquina';
            app.posGar = uieditfield(app.GargaloTab, 'numeric');
            app.Button_4 = uibutton(app.GargaloTab, 'push');
            app.Button_4.Icon = 'foto_planta.PNG';
            app.Button_4.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogBox_SETUPMAQUINAS(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupMquinasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupMquinasUIFigure)
        end
    end
end