classdef DialogBox_SKU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button200ML        matlab.ui.control.Button
        Button600ML        matlab.ui.control.Button
        mlLabel            matlab.ui.control.Label
        mlLabel_2          matlab.ui.control.Label
        OKButton           matlab.ui.control.Button
        QualSKULabel       matlab.ui.control.Label
        reaEditFieldLabel  matlab.ui.control.Label
        dialogSKU          matlab.ui.control.NumericEditField
        m2Label            matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Main app object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcnSKU(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Send values back to main window by calling its public function
            updateVALORESSKU(app.CallingApp,app.dialogSKU.Value);
            
            % delete options dialog
            delete(app);
        end

        % Button pushed function: Button200ML
        function Button200MLPushed(app, event)
            % set do valor da Área SKU de 200ml
            app.dialogSKU.Value = 0.00031416; % Area sku 200ml em metros^2 
        end

        % Button pushed function: Button600ML
        function Button600MLPushed(app, event)
            app.dialogSKU.Value = 0.00031416*2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button200ML = uibutton(app.UIFigure, 'push');
            app.Button200ML.ButtonPushedFcn = createCallbackFcn(app, @Button200MLPushed, true);
            app.Button200ML.Icon = '200ml.png';
            app.Button200ML.Text = '';
            app.Button600ML = uibutton(app.UIFigure, 'push');
            app.Button600ML.ButtonPushedFcn = createCallbackFcn(app, @Button600MLPushed, true);
            app.Button600ML.Icon = '600ml.png';
            app.Button600ML.Text = '';
            app.mlLabel = uilabel(app.UIFigure);
            app.mlLabel.Text = '200ml';
            app.mlLabel_2 = uilabel(app.UIFigure);
            app.mlLabel_2.Text = '600ml';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QualSKULabel = uilabel(app.UIFigure);
            app.QualSKULabel.Text = 'Qual SKU?';
            app.reaEditFieldLabel = uilabel(app.UIFigure);
            app.reaEditFieldLabel.Text = 'Área';
            app.dialogSKU = uieditfield(app.UIFigure, 'numeric');
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = 'm2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogBox_SKU(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcnSKU(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end