classdef Main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_WindowUIFigure  matlab.ui.Figure
        GerarreltorioButton  matlab.ui.control.Button
        ConfiguraesPanel     matlab.ui.container.Panel
        SKUButton            matlab.ui.control.Button
        SetupMquinasButton   matlab.ui.control.Button
        PulmesButton         matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        OtimizadordelinhasdeenvaseLabel  matlab.ui.control.Label
        HelpButton           matlab.ui.control.Button
    end


    properties (Access = private)
        DialogApp                   % Options dialog app
    end

    properties (Access = public)
        AreaSku
        MTTR
        MTBF
        GAR
        AreaP
        pGar
    end

    methods (Access = public)
        
        function updateVALORESMAQUINA(app,veloGAR,dialogMTBF1,dialogMTBF2,dialogMTBF3,dialogMTBF4,...
            dialogMTBF5,dialogMTBF6,dialogMTBF7,dialogMTBF8,dialogMTTR1,dialogMTTR2,dialogMTTR3,dialogMTTR4,...
            dialogMTTR5,dialogMTTR6,dialogMTTR7,dialogMTTR8,posGar)
            
            
            % armazenamento do inputs dos valores de MTBF
            mainMTBF1 = dialogMTBF1;
            mainMTBF2 = dialogMTBF2;
            mainMTBF3 = dialogMTBF3;
            mainMTBF4 = dialogMTBF4;
            mainMTBF5 = dialogMTBF5;
            mainMTBF6 = dialogMTBF6;
            mainMTBF7 = dialogMTBF7;
            mainMTBF8 = dialogMTBF8;
            
            % armazenamento dos inputs dos valores de MTTR
            mainMTTR1 = dialogMTTR1;
            mainMTTR2 = dialogMTTR2;
            mainMTTR3 = dialogMTTR3;
            mainMTTR4 = dialogMTTR4;
            mainMTTR5 = dialogMTTR5;
            mainMTTR6 = dialogMTTR6;
            mainMTTR7 = dialogMTTR7;
            mainMTTR8 = dialogMTTR8;
            
            
            % criação da lista relatorio
            app.pGar = posGar;
            app.GAR = veloGAR;
            app.MTTR = [mainMTTR1 mainMTTR2 mainMTTR3 mainMTTR4 mainMTTR5 mainMTTR6 mainMTTR7 mainMTTR8];
            app.MTBF = [mainMTBF1 mainMTBF2 mainMTBF3 mainMTBF4 mainMTBF5 mainMTBF6 mainMTBF7 mainMTBF8];
            
        end
        
        function updateVALORESSKU(app,dialogSKU)
            
            mainASKU = dialogSKU;

            
            % criação da lista relatorio
             
            app.AreaSku = [mainASKU mainASKU mainASKU mainASKU mainASKU mainASKU mainASKU mainASKU];
            return 
        end
        
        function updateVALORESPULMAO(app,L1,L2,L3,L4,L5,L6,L7,L8,c1,c2,c3,c4,c5,c6,c7,c8)
            %{
            updateVALORESPULMAO(app,areapulmo1EditField,areapulmo2EditField,... 
            areapulmo3EditField,areapulmo4EditField,areapulmo5EditField,...
            areapulmo6EditField,areapulmo7EditField,areapulmo8EditField)
            %}
           
            mainAPULMAO1 = L1*c1;
            mainAPULMAO2 = L2*c2;
            mainAPULMAO3 = L3*c3;
            mainAPULMAO4 = L4*c4;
            mainAPULMAO5 = L5*c5;
            mainAPULMAO6 = L6*c6;
            mainAPULMAO7 = L7*c7;
            mainAPULMAO8 = L8*c8;
            
            app.AreaP = [mainAPULMAO1  mainAPULMAO2  mainAPULMAO3 mainAPULMAO4 mainAPULMAO5 mainAPULMAO6 mainAPULMAO7 mainAPULMAO8];
            return
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcnMAIN(app)

        end

        % Button pushed function: SetupMquinasButton
        function SetupMaquinasButtonPushed(app, event)
            % Call dialog box with input values
            app.DialogApp = DialogBox_SETUPMAQUINAS(app);
        end

        % Button pushed function: SKUButton
        function SKUButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = DialogBox_SKU(app);
        end

        % Button pushed function: PulmesButton
        function PulmesButtonPushed(app, event)
            app.DialogApp = DialogBox_PULMOES(app);
        end

        % Button pushed function: GerarreltorioButton
        function GerarreltorioButtonPushed(app, event)
            app.DialogApp = DialogBox_RELATORIO(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.DialogApp = DialogBox_AJUDA(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_WindowUIFigure = uifigure;
            app.Main_WindowUIFigure.Name = 'Main_Window';
            app.GerarreltorioButton = uibutton(app.Main_WindowUIFigure, 'push');
            app.GerarreltorioButton.ButtonPushedFcn = createCallbackFcn(app, @GerarreltorioButtonPushed, true);
            app.GerarreltorioButton.Text = 'Gerar relátorio';
            app.ConfiguraesPanel = uipanel(app.Main_WindowUIFigure);
            app.ConfiguraesPanel.Title = 'Configurações:';
            app.SKUButton = uibutton(app.ConfiguraesPanel, 'push');
            app.SKUButton.ButtonPushedFcn = createCallbackFcn(app, @SKUButtonPushed, true);
            app.SKUButton.Text = 'SKU';
            app.SetupMquinasButton = uibutton(app.ConfiguraesPanel, 'push');
            app.SetupMquinasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupMaquinasButtonPushed, true);
            app.SetupMquinasButton.Text = 'Setup Máquinas';
            app.PulmesButton = uibutton(app.ConfiguraesPanel, 'push');
            app.PulmesButton.ButtonPushedFcn = createCallbackFcn(app, @PulmesButtonPushed, true);
            app.PulmesButton.Text = 'Pulmões';
            app.Button = uibutton(app.Main_WindowUIFigure, 'push');
            app.Button.Icon = 'insper_logo.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Main_WindowUIFigure, 'push');
            app.Button_2.Icon = 'Coca-Cola_Femsa_Logo.png';
            app.Button_2.Text = '';
            app.OtimizadordelinhasdeenvaseLabel = uilabel(app.Main_WindowUIFigure);
            app.OtimizadordelinhasdeenvaseLabel.Text = 'Otimizador de linhas de envase';
            app.HelpButton = uibutton(app.Main_WindowUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_WindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcnMAIN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_WindowUIFigure)
        end
    end
end